classdef MORSE_CODE_ENCODER_DECODER < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure              matlab.ui.Figure
		INPUTEditField        matlab.ui.control.EditField
		INPUTEditFieldLabel   matlab.ui.control.Label
		SAVEButton            matlab.ui.control.Button
		UPLOADAUDIOButton     matlab.ui.control.Button
		PLAYButton            matlab.ui.control.Button
		DECODEButton          matlab.ui.control.Button
		ENCODEButton          matlab.ui.control.Button
		OUTPUTEditField       matlab.ui.control.EditField
		OUTPUTEditFieldLabel  matlab.ui.control.Label
	end

	
	properties (Access = private)
		 UploadedFilePath % Store the uploaded file path
	end
	
	methods (Access = private)
function morseCode = textToMorse(~,text)
morseDict = containers.Map({'A','B','C','D','E','F','G','H','I','J','K','L','M','N','O','P','Q','R','S','T','U','V','W','X','Y','Z','0','1','2','3','4','5','6','7','8','9',' '}, ...
{'.-','-...','-.-.','-..','.','..-.','--.','....','..','.---','-.-','.-..','--','-.','---','.--.','--.-','.-.','...','-','..-','...-','.--','-..-','-.--','--..','-----','.----','..---','...--','....-','.....','-....','--...','---..','----.','-.-.-'});
morseCode = '';
for i = 1:length(text)
char = upper(text(i));
if isKey(morseDict, char)
morseCode = [morseCode, morseDict(char), ' '];
end
end
end
function results = func5(app)
end
end
methods (Access = private)
function SaveMorse(app,morseCode, filename)
dotDuration = 0.1;
dashDuration = 0.3;
frequency = 1000;
sampleRate = 8000;
audioSignal = [];
for i = 1:length(morseCode)
if morseCode(i) == '.'
t = 0:1/sampleRate:dotDuration;
audioSignal = [audioSignal, 0.5*sin(2*pi*frequency*t)];
elseif morseCode(i) == '-'
t = 0:1/sampleRate:dashDuration;
audioSignal = [audioSignal, 0.5*sin(2*pi*frequency*t)];
end
audioSignal = [audioSignal, zeros(1, sampleRate*0.1)];
end
audiowrite(filename, audioSignal, sampleRate);
end
function playMorse(app,char)
morseCode = textToMorse(app,char);
dotDuration = 0.1;
dashDuration = 0.3;
frequency = 1000;
sampleRate = 8000;
audioSignal = [];
for i = 1:length(morseCode)
if morseCode(i) == '.'
t = 0:1/sampleRate:dotDuration;
audioSignal = [audioSignal, 0.5*sin(2*pi*frequency*t)];
elseif morseCode(i) == '-'
t = 0:1/sampleRate:dashDuration;
audioSignal = [audioSignal, 0.5*sin(2*pi*frequency*t)];
end
audioSignal = [audioSignal, zeros(1, sampleRate*0.1)];
end
sound(audioSignal,sampleRate)
end
function decodedText = decoder(app,wavfile)
threshold = 0.05;
x = audioread(wavfile);

if isempty(x)
        error('Audio file is empty.'); % Catch empty audio errors
end
x2 = abs(x);
y = filter(ones(1,20)/20,1, x2);
z = y > threshold;
b = diff(z);
c = b(b~=0);
c2 = find(b~=0); 
tokens = -c .* diff([0; c2]);
tokens2 = tokens;
cut_t = mean(tokens2(tokens2>0));
cut_s = mean(tokens2(tokens2<0));
tokens2(tokens > 0 & tokens < cut_t) = 1;
tokens2(tokens > 0 & tokens > cut_t) = 2;
tokens2(tokens < 0 & tokens > cut_s) = -1;
tokens2(tokens < 0 & tokens < cut_s) = -2;
tokens2 = [tokens2(2:end); -2];
tokens2(tokens2 == -1) = [];
tokens3 = tokens2;
tokens4 = [];
ctr = 1;
start_idx = 1;
toparse = find(tokens3(start_idx:end) == -2);
for j=1:length(toparse)
a = toparse(j);
temp = tokens3(start_idx:a-1);
tokens4(:,j) = [temp;zeros(6-length(temp),1)];
start_idx = a+1; 
end
LUT = [1 2 2 2 1 1 2 1 1 1 2 1 2 2 2 1 2 1 1 2 1 1 1 2 2 2 1 1 1 1 1 2 2 2 2 2 2;
2 1 1 1 0 1 2 1 1 2 1 2 2 1 2 2 2 2 1 0 1 1 2 1 1 2 2 1 1 1 1 1 2 2 2 2 1;
0 1 2 1 0 2 1 1 0 2 2 1 0 0 2 2 1 1 1 0 2 1 2 1 2 1 2 2 1 1 1 1 1 2 2 2 2;
0 1 1 0 0 1 0 1 0 2 0 1 0 0 0 1 2 0 0 0 0 2 0 2 2 1 2 2 2 1 1 1 1 1 2 2 1;
0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 2 2 2 2 1 1 1 1 1 2 2;
0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0 0];
decode = 'ABCDEFGHIJKLMNOPQRSTUVWXYZ1234567890 ';
[~,col] = size(tokens4);
morse = [];
for j = 1:col
idx = all(tokens4(:,j)==LUT);
morse = [morse,decode(idx)];
if isempty(morse(j))
morse(j) = '_';
end
end
decodedText = morse;
end

end


	% Callbacks that handle component events
	methods (Access = private)

		% Callback function
		function INPUTEditFieldValueChanging(app, event)
		  % inputText = event.Value;
            %morseCode = textToMorse(app,inputText);
            %app.outputEditField.Value = morseCode;
            %if(length(inputText)>0)
             %   playMorse(app,inputText(end))
             %end
		   % Button pushed function: encodeButton
		end

		% Button pushed function: ENCODEButton
		function ENCODEButtonPushed(app, event)
	      inputText = app.INPUTEditField.Value;
	      % Convert text to Morse code
	      morseCode = textToMorse(app, inputText);
	      % Display Morse code in the output field
	      app.OUTPUTEditField.Value = morseCode;
		end

		% Value changed function: OUTPUTEditField
		function OUTPUTEditFieldValueChanged(app, event)
	
		end

		% Button pushed function: SAVEButton
		function SAVEButtonPushed(app, event)
		 morseCode = app.OUTPUTEditField.Value;
           [file, path] = uiputfile('D:\\gg.wav', 'gg');
           if isequal(file, 0) || isequal(path, 0)
           return;
           end
           fullPath = fullfile(path, file);
           SaveMorse(app,morseCode, fullPath);
          
		end

		% Button pushed function: PLAYButton
		function PLAYButtonPushed(app, event)
			inputText = app.INPUTEditField.Value;
                 % Convert text to Morse code
                 morseCode = textToMorse(app, inputText);
                 % Display Morse code in the output field
                 app.OUTPUTEditField.Value = morseCode;
                 % Play the encoded Morse code (after encoding)
                 playMorse(app, morseCode);

		end

		% Button pushed function: UPLOADAUDIOButton
		function UPLOADAUDIOButtonPushed(app, event)
		    [file, path] = uigetfile('*.wav', 'Select a Morse Code Audio File');
    if isequal(file, 0) || isequal(path, 0)
        return; % If no file is selected, do nothing
    end
    
    % Store the full file path in the app property
    app.UploadedFilePath = fullfile(path, file);
    
    % Update the input field with the filename only
    app.INPUTEditField.Value = file;

		end

		% Button pushed function: DECODEButton
		function DECODEButtonPushed(app, event)
			% Check if a file has been uploaded
    if isempty(app.UploadedFilePath)
        uialert(app.UIFigure, 'Please upload an audio file first.', 'Error');
        return;
    end
    
    % Decode the uploaded file
    decodedText = decoder(app, app.UploadedFilePath);
    
    % Display decoded text in the output field
    app.OUTPUTEditField.Value = decodedText;
		end

		% Value changing function: INPUTEditField
		function INPUTEditFieldValueChanging2(app, event)
			changingValue = event.Value;
			% inputText = event.Value;
			%morseCode = textToMorse(app,inputText);
			%app.outputEditField.Value = morseCode;
			%if(length(inputText)>0)
			% playMorse(app,inputText(end))
			%end
			% Button pushed function: encodeButton

		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.OUTPUTEditFieldLabel = uilabel(app.UIFigure);
			app.OUTPUTEditFieldLabel.Text = 'OUTPUT';
			app.OUTPUTEditField = uieditfield(app.UIFigure, 'text');
			app.OUTPUTEditField.ValueChangedFcn = createCallbackFcn(app, @OUTPUTEditFieldValueChanged, true);
			app.ENCODEButton = uibutton(app.UIFigure, 'push');
			app.ENCODEButton.ButtonPushedFcn = createCallbackFcn(app, @ENCODEButtonPushed, true);
			app.ENCODEButton.Text = 'ENCODE';
			app.DECODEButton = uibutton(app.UIFigure, 'push');
			app.DECODEButton.ButtonPushedFcn = createCallbackFcn(app, @DECODEButtonPushed, true);
			app.DECODEButton.Text = 'DECODE';
			app.PLAYButton = uibutton(app.UIFigure, 'push');
			app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
			app.PLAYButton.Text = 'PLAY';
			app.UPLOADAUDIOButton = uibutton(app.UIFigure, 'push');
			app.UPLOADAUDIOButton.ButtonPushedFcn = createCallbackFcn(app, @UPLOADAUDIOButtonPushed, true);
			app.UPLOADAUDIOButton.Text = 'UPLOAD AUDIO';
			app.SAVEButton = uibutton(app.UIFigure, 'push');
			app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
			app.SAVEButton.Text = 'SAVE';
			app.INPUTEditFieldLabel = uilabel(app.UIFigure);
			app.INPUTEditFieldLabel.Text = 'INPUT';
			app.INPUTEditField = uieditfield(app.UIFigure, 'text');
			app.INPUTEditField.ValueChangingFcn = createCallbackFcn(app, @INPUTEditFieldValueChanging2, true);
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = MORSE_CODE_ENCODER_DECODER

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end